classdef tutorial_03 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        NewtonsCalculatorLabel         matlab.ui.control.Label
        ForceNEditField                matlab.ui.control.NumericEditField
        ForceNEditFieldLabel           matlab.ui.control.Label
        CalculateButton                matlab.ui.control.Button
        Accelerationms2EditField       matlab.ui.control.NumericEditField
        Accelerationms2EditFieldLabel  matlab.ui.control.Label
        MasskgEditField                matlab.ui.control.NumericEditField
        MasskgEditFieldLabel           matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)

            evalin('base', 'clear all')
            mass = app.MasskgEditField.Value;
            assignin('base','mass',mass)
            
            acc = app.Accelerationms2EditField.Value;
            assignin('base','acc',acc)
            
            force = mass * acc;
            assignin('base','force',force)
            app.ForceNEditField.Value = force;

            x = [mass, acc, force];
            assignin('base','array',x)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MasskgEditFieldLabel = uilabel(app.UIFigure);
            app.MasskgEditFieldLabel.Text = 'Mass [kg]';
            app.MasskgEditField = uieditfield(app.UIFigure, 'numeric');
            app.Accelerationms2EditFieldLabel = uilabel(app.UIFigure);
            app.Accelerationms2EditFieldLabel.Text = 'Acceleration [m/s^2]';
            app.Accelerationms2EditField = uieditfield(app.UIFigure, 'numeric');
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.ForceNEditFieldLabel = uilabel(app.UIFigure);
            app.ForceNEditFieldLabel.Text = 'Force [N]';
            app.ForceNEditField = uieditfield(app.UIFigure, 'numeric');
            app.NewtonsCalculatorLabel = uilabel(app.UIFigure);
            app.NewtonsCalculatorLabel.Text = 'Newton''s Calculator';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tutorial_03

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end